classdef SpectralGuidedWaves < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadMenu                       matlab.ui.container.Menu
        SaveMenu                       matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        MeshTab                        matlab.ui.container.Tab
        MeshFig                        matlab.ui.control.UIAxes
        ActuatorcoordinatesLabel       matlab.ui.control.Label
        xpztEditFieldLabel             matlab.ui.control.Label
        xpztEditField                  matlab.ui.control.NumericEditField
        PlateLengthEditFieldLabel      matlab.ui.control.Label
        PlateLengthEditField           matlab.ui.control.NumericEditField
        PlateWidthEditFieldLabel       matlab.ui.control.Label
        PlateWidthEditField            matlab.ui.control.NumericEditField
        ypztEditFieldLabel             matlab.ui.control.Label
        ypztEditField                  matlab.ui.control.NumericEditField
        NxEditFieldLabel               matlab.ui.control.Label
        NxEditField                    matlab.ui.control.NumericEditField
        NyEditFieldLabel               matlab.ui.control.Label
        NyEditField                    matlab.ui.control.NumericEditField
        NumberofcorrespondingintepolationpointsLabel  matlab.ui.control.Label
        DelaminationLabel              matlab.ui.control.Label
        DelaminationcentercoordinatesLabel  matlab.ui.control.Label
        xCenterEditFieldLabel          matlab.ui.control.Label
        xCenterEditField               matlab.ui.control.NumericEditField
        yCenterEditFieldLabel          matlab.ui.control.Label
        yCenterEditField               matlab.ui.control.NumericEditField
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        DelaminationsemimajorandsemiminoraxislengthLabel  matlab.ui.control.Label
        pztradiusEditFieldLabel        matlab.ui.control.Label
        pztradiusEditField             matlab.ui.control.NumericEditField
        DelaminationRotationAngledegEditFieldLabel  matlab.ui.control.Label
        DelaminationRotationAngledegEditField  matlab.ui.control.NumericEditField
        CreateMeshButton               matlab.ui.control.Button
        MaterialsTab                   matlab.ui.container.Tab
        MatrixLabel                    matlab.ui.control.Label
        FibresLabel                    matlab.ui.control.Label
        EmEditFieldLabel               matlab.ui.control.Label
        EmEditField                    matlab.ui.control.NumericEditField
        EfEditFieldLabel               matlab.ui.control.Label
        EfEditField                    matlab.ui.control.NumericEditField
        nimEditFieldLabel              matlab.ui.control.Label
        nimEditField                   matlab.ui.control.NumericEditField
        nifEditFieldLabel              matlab.ui.control.Label
        nifEditField                   matlab.ui.control.NumericEditField
        YoungsModulusGPaLabel          matlab.ui.control.Label
        PiossonratioLabel              matlab.ui.control.Label
        Densitykgm3Label               matlab.ui.control.Label
        rhomEditFieldLabel             matlab.ui.control.Label
        rhomEditField                  matlab.ui.control.NumericEditField
        rhofEditFieldLabel             matlab.ui.control.Label
        rhofEditField                  matlab.ui.control.NumericEditField
        NooflayersLabel                matlab.ui.control.Label
        layEditFieldLabel              matlab.ui.control.Label
        layEditField                   matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        lay1EditField                  matlab.ui.control.NumericEditField
        LayupLabel                     matlab.ui.control.Label
        VolLabel                       matlab.ui.control.Label
        EditFieldLabel_16              matlab.ui.control.Label
        vol1EditField                  matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        lay2EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_17              matlab.ui.control.Label
        vol2EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_2               matlab.ui.control.Label
        lay3EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_18              matlab.ui.control.Label
        vol3EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_3               matlab.ui.control.Label
        lay4EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_19              matlab.ui.control.Label
        vol4EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_4               matlab.ui.control.Label
        lay5EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_20              matlab.ui.control.Label
        vol5EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_5               matlab.ui.control.Label
        lay6EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_21              matlab.ui.control.Label
        vol6EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_6               matlab.ui.control.Label
        lay7EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_22              matlab.ui.control.Label
        vol7EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_7               matlab.ui.control.Label
        lay8EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_23              matlab.ui.control.Label
        vol8EditField                  matlab.ui.control.NumericEditField
        ThicknessmmLabel               matlab.ui.control.Label
        EditFieldLabel_32              matlab.ui.control.Label
        h1EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_33              matlab.ui.control.Label
        h2EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_34              matlab.ui.control.Label
        h3EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_35              matlab.ui.control.Label
        h4EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_36              matlab.ui.control.Label
        h5EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_37              matlab.ui.control.Label
        h6EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_38              matlab.ui.control.Label
        h7EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_39              matlab.ui.control.Label
        h8EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_8               matlab.ui.control.Label
        lay9EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_24              matlab.ui.control.Label
        vol9EditField                  matlab.ui.control.NumericEditField
        EditFieldLabel_9               matlab.ui.control.Label
        lay10EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_25              matlab.ui.control.Label
        vol10EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_10              matlab.ui.control.Label
        lay11EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_26              matlab.ui.control.Label
        vol11EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_11              matlab.ui.control.Label
        lay12EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_27              matlab.ui.control.Label
        vol12EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_12              matlab.ui.control.Label
        lay13EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_28              matlab.ui.control.Label
        vol13EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_13              matlab.ui.control.Label
        lay14EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_29              matlab.ui.control.Label
        vol14EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_14              matlab.ui.control.Label
        lay15EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_30              matlab.ui.control.Label
        vol15EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_15              matlab.ui.control.Label
        lay16EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_31              matlab.ui.control.Label
        vol16EditField                 matlab.ui.control.NumericEditField
        EditFieldLabel_40              matlab.ui.control.Label
        h9EditField                    matlab.ui.control.NumericEditField
        EditFieldLabel_41              matlab.ui.control.Label
        h10EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel_42              matlab.ui.control.Label
        h11EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel_43              matlab.ui.control.Label
        h12EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel_44              matlab.ui.control.Label
        h13EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel_45              matlab.ui.control.Label
        h14EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel_46              matlab.ui.control.Label
        h15EditField                   matlab.ui.control.NumericEditField
        EditFieldLabel_47              matlab.ui.control.Label
        h16EditField                   matlab.ui.control.NumericEditField
        PiezoelectrictransducerpropertiesLabel  matlab.ui.control.Label
        YoungsModulusGPaLabel_2        matlab.ui.control.Label
        PiossonratioLabel_2            matlab.ui.control.Label
        Densitykgm3Label_2             matlab.ui.control.Label
        YE11EditFieldLabel             matlab.ui.control.Label
        YE11EditField                  matlab.ui.control.NumericEditField
        nu11EditFieldLabel             matlab.ui.control.Label
        nu11EditField                  matlab.ui.control.NumericEditField
        rho_pztEditFieldLabel          matlab.ui.control.Label
        rho_pztEditField               matlab.ui.control.NumericEditField
        YE33EditFieldLabel             matlab.ui.control.Label
        YE33EditField                  matlab.ui.control.NumericEditField
        Chargeconstantmatrix1012CNLabel  matlab.ui.control.Label
        Voltageconstantmatrix103VmNLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        dp11                           matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        dp12                           matlab.ui.control.NumericEditField
        Label_4                        matlab.ui.control.Label
        dp13                           matlab.ui.control.NumericEditField
        Label_5                        matlab.ui.control.Label
        dp14                           matlab.ui.control.NumericEditField
        Label_6                        matlab.ui.control.Label
        dp15                           matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        dp16                           matlab.ui.control.NumericEditField
        Label_8                        matlab.ui.control.Label
        dp21                           matlab.ui.control.NumericEditField
        Label_9                        matlab.ui.control.Label
        dp22                           matlab.ui.control.NumericEditField
        Label_10                       matlab.ui.control.Label
        dp23                           matlab.ui.control.NumericEditField
        Label_11                       matlab.ui.control.Label
        dp24                           matlab.ui.control.NumericEditField
        Label_12                       matlab.ui.control.Label
        dp25                           matlab.ui.control.NumericEditField
        Label_13                       matlab.ui.control.Label
        dp26                           matlab.ui.control.NumericEditField
        Label_14                       matlab.ui.control.Label
        dp31                           matlab.ui.control.NumericEditField
        Label_15                       matlab.ui.control.Label
        dp32                           matlab.ui.control.NumericEditField
        Label_16                       matlab.ui.control.Label
        dp33                           matlab.ui.control.NumericEditField
        Label_17                       matlab.ui.control.Label
        dp34                           matlab.ui.control.NumericEditField
        Label_18                       matlab.ui.control.Label
        dp35                           matlab.ui.control.NumericEditField
        Label_19                       matlab.ui.control.Label
        dp36                           matlab.ui.control.NumericEditField
        Label_20                       matlab.ui.control.Label
        gp11                           matlab.ui.control.NumericEditField
        Label_21                       matlab.ui.control.Label
        gp12                           matlab.ui.control.NumericEditField
        Label_22                       matlab.ui.control.Label
        gp13                           matlab.ui.control.NumericEditField
        Label_23                       matlab.ui.control.Label
        gp14                           matlab.ui.control.NumericEditField
        Label_24                       matlab.ui.control.Label
        gp15                           matlab.ui.control.NumericEditField
        Label_25                       matlab.ui.control.Label
        gp16                           matlab.ui.control.NumericEditField
        Label_26                       matlab.ui.control.Label
        gp21                           matlab.ui.control.NumericEditField
        Label_27                       matlab.ui.control.Label
        gp22                           matlab.ui.control.NumericEditField
        Label_28                       matlab.ui.control.Label
        gp23                           matlab.ui.control.NumericEditField
        Label_29                       matlab.ui.control.Label
        gp24                           matlab.ui.control.NumericEditField
        Label_30                       matlab.ui.control.Label
        gp25                           matlab.ui.control.NumericEditField
        Label_31                       matlab.ui.control.Label
        gp26                           matlab.ui.control.NumericEditField
        Label_32                       matlab.ui.control.Label
        gp31                           matlab.ui.control.NumericEditField
        Label_33                       matlab.ui.control.Label
        gp32                           matlab.ui.control.NumericEditField
        Label_34                       matlab.ui.control.Label
        gp33                           matlab.ui.control.NumericEditField
        Label_35                       matlab.ui.control.Label
        gp34                           matlab.ui.control.NumericEditField
        Label_36                       matlab.ui.control.Label
        gp35                           matlab.ui.control.NumericEditField
        Label_37                       matlab.ui.control.Label
        gp36                           matlab.ui.control.NumericEditField
        dLabel                         matlab.ui.control.Label
        gLabel                         matlab.ui.control.Label
        lay1Label                      matlab.ui.control.Label
        lay2Label                      matlab.ui.control.Label
        lay3Label                      matlab.ui.control.Label
        lay4Label                      matlab.ui.control.Label
        lay5Label                      matlab.ui.control.Label
        lay6Label                      matlab.ui.control.Label
        lay7Label                      matlab.ui.control.Label
        lay8Label                      matlab.ui.control.Label
        lay9Label                      matlab.ui.control.Label
        lay10Label                     matlab.ui.control.Label
        lay11Label                     matlab.ui.control.Label
        lay12Label                     matlab.ui.control.Label
        lay13Label                     matlab.ui.control.Label
        lay14Label                     matlab.ui.control.Label
        lay15Label                     matlab.ui.control.Label
        lay16Label                     matlab.ui.control.Label
        ShellstructurepropertiesLabel  matlab.ui.control.Label
        PiezoelectrictransducerthicknessmmEditFieldLabel  matlab.ui.control.Label
        pzt_thicknessEditField         matlab.ui.control.NumericEditField
        DelaminationlayerLabel         matlab.ui.control.Label
        EditFieldLabel_48              matlab.ui.control.Label
        delamination_layerEditField    matlab.ui.control.NumericEditField
        SignalTab                      matlab.ui.container.Tab
        CarrierFrequencykHzEditFieldLabel  matlab.ui.control.Label
        CarrierFrequencykHzEditField   matlab.ui.control.NumericEditField
        ModulationFrequencykHzEditFieldLabel  matlab.ui.control.Label
        ModulationFrequencykHzEditField  matlab.ui.control.NumericEditField
        NoofsamplesEditFieldLabel      matlab.ui.control.Label
        NoofsamplesEditField           matlab.ui.control.NumericEditField
        TotalpropagationtimemsEditFieldLabel  matlab.ui.control.Label
        TotalpropagationtimemsEditField  matlab.ui.control.NumericEditField
        NooftimeframesEditFieldLabel   matlab.ui.control.Label
        NooftimeframesEditField        matlab.ui.control.NumericEditField
        PeakvoltageVEditFieldLabel     matlab.ui.control.Label
        PeakvoltageVEditField          matlab.ui.control.NumericEditField
        HannTimeFig                    matlab.ui.control.UIAxes
        HannFrequencyFig               matlab.ui.control.UIAxes
        ProcessingTab                  matlab.ui.container.Tab
        Label                          matlab.ui.control.Label
        Mode                           matlab.ui.control.Switch
        FieldvariableDropDownLabel     matlab.ui.control.Label
        FieldvariableDropDown          matlab.ui.control.DropDown
        ShellsurfaceDropDownLabel      matlab.ui.control.Label
        ShellsurfaceDropDown           matlab.ui.control.DropDown
        NotificationTextAreaLabel      matlab.ui.control.Label
        NotificationTextArea           matlab.ui.control.TextArea
        Motion1CheckBox                matlab.ui.control.CheckBox
        Motion2CheckBox                matlab.ui.control.CheckBox
        Motion3CheckBox                matlab.ui.control.CheckBox
        Motion4CheckBox                matlab.ui.control.CheckBox
        Motion5CheckBox                matlab.ui.control.CheckBox
        Motion6CheckBox                matlab.ui.control.CheckBox
        Motion7CheckBox                matlab.ui.control.CheckBox
        Motion8CheckBox                matlab.ui.control.CheckBox
        Motion9CheckBox                matlab.ui.control.CheckBox
        DeleteframesSwitchLabel        matlab.ui.control.Label
        DeleteframesSwitch             matlab.ui.control.Switch
        NormalizationSwitchLabel       matlab.ui.control.Label
        NormalizationSwitch            matlab.ui.control.Switch
        RunButton                      matlab.ui.control.StateButton
        ColormapDropDownLabel          matlab.ui.control.Label
        ColormapDropDown               matlab.ui.control.DropDown
        caxiscutEditFieldLabel         matlab.ui.control.Label
        caxiscutEditField              matlab.ui.control.NumericEditField
        Label_38                       matlab.ui.control.Label
        Lamp_Progres10                 matlab.ui.control.Lamp
        Label_39                       matlab.ui.control.Label
        Lamp_Progres20                 matlab.ui.control.Lamp
        Label_40                       matlab.ui.control.Label
        Lamp_Progres30                 matlab.ui.control.Lamp
        Label_41                       matlab.ui.control.Label
        Lamp_Progres40                 matlab.ui.control.Lamp
        Label_42                       matlab.ui.control.Label
        Lamp_Progres50                 matlab.ui.control.Lamp
        Label_43                       matlab.ui.control.Label
        Lamp_Progres60                 matlab.ui.control.Lamp
        Label_44                       matlab.ui.control.Label
        Lamp_Progres70                 matlab.ui.control.Lamp
        Label_45                       matlab.ui.control.Label
        Lamp_Progres80                 matlab.ui.control.Lamp
        Label_46                       matlab.ui.control.Label
        Lamp_Progres90                 matlab.ui.control.Lamp
        Label_47                       matlab.ui.control.Label
        Lamp_Progres100                matlab.ui.control.Lamp
        FilenameEditFieldLabel         matlab.ui.control.Label
        FilenameEditField              matlab.ui.control.EditField
    end


    properties (Access = private)
        modelfolder = 'flat_shell'; % name of folder
        modelname = 'GUI'; 
        shape_order = 5; % element shape function order, Number of nodes in one direction is shape_order+1
        CharacteristicLengthFactor = 0.08; % GMSH options
        CharacteristicLengthMin = 0.001; 
        CharacteristicLengthMax = 0.2;
        Smoothing = 1;
    end

    methods (Access = private)
    
        function changeFilenameColor(app)
            app.FilenameEditField.FontColor = [1,0,0]; % red
            app.FilenameEditFieldLabel.FontColor = [1,0,0]; % red
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.MeshFig.Visible = 'off';
            app.HannTimeFig.Visible = 'off';
            app.HannFrequencyFig.Visible = 'off';
            
            app.lay9EditField.Visible = 'off';
            app.lay10EditField.Visible = 'off';
            app.lay11EditField.Visible = 'off';
            app.lay12EditField.Visible = 'off';
            app.lay13EditField.Visible = 'off';
            app.lay14EditField.Visible = 'off';
            app.lay15EditField.Visible = 'off';
            app.lay16EditField.Visible = 'off';
            app.vol9EditField.Visible = 'off';
            app.vol10EditField.Visible = 'off';
            app.vol11EditField.Visible = 'off';
            app.vol12EditField.Visible = 'off';
            app.vol13EditField.Visible = 'off';
            app.vol14EditField.Visible = 'off';
            app.vol15EditField.Visible = 'off';
            app.vol16EditField.Visible = 'off';
            app.h9EditField.Visible = 'off';
            app.h10EditField.Visible = 'off';
            app.h11EditField.Visible = 'off';
            app.h12EditField.Visible = 'off';
            app.h13EditField.Visible = 'off';
            app.h14EditField.Visible = 'off';
            app.h15EditField.Visible = 'off';
            app.h16EditField.Visible = 'off';
            
            app.lay9Label.Visible = 'off';
            app.lay10Label.Visible = 'off';
            app.lay11Label.Visible = 'off';
            app.lay12Label.Visible = 'off';
            app.lay13Label.Visible = 'off';
            app.lay14Label.Visible = 'off';
            app.lay15Label.Visible = 'off';
            app.lay16Label.Visible = 'off';
        end

        % Button pushed function: CreateMeshButton
        function CreateMeshButtonPushed(app, event)
            % prepare model output path
            figure_output_path = prepare_figure_paths(app.modelfolder,app.modelname);
            L = app.PlateLengthEditField.Value;
            W = app.PlateWidthEditField.Value;
            a= app.aEditField.Value;
            b= app.bEditField.Value;
            xCenter = app.xCenterEditField.Value;
            yCenter = app.yCenterEditField.Value;
            rotAngle = app.DelaminationRotationAngledegEditField.Value;
            r = app.pztradiusEditField.Value;
            xpzt = app.xpztEditField.Value;
            ypzt = app.ypztEditField.Value;
            mesh_filename = app.FilenameEditField.Value;
            figfilename = [figure_output_path,mesh_filename];
            % create mesh
            [msh,nodes,coords,den_under,den_above,I_G,I_L] = automesh_delam_GUI...
                            (L,W,a,b,xCenter,yCenter,rotAngle,r,xpzt,ypzt,app.shape_order,app.CharacteristicLengthFactor,app.CharacteristicLengthMin,app.CharacteristicLengthMax,app.Smoothing,mesh_filename,app.modelfolder);
            
            % plot mesh figure in GUI
            app.MeshFig.Visible = 'on';
            X=msh.POS(:,1);
            Y=msh.POS(:,2);
            nRegions = max(msh.QUADS(:,5));
            nbElements = size(msh.QUADS,1);
            colour = 'rgybmc';
            c=1;
            for k=1:nRegions
                c1=c;
                while(c <= nbElements && msh.QUADS(c,5)==k)
                    c=c+1;
                end 
                qnodes = msh.QUADS(c1:c-1,1:4);
                patch(app.MeshFig,'faces',qnodes,'vertices',[X Y],'facecolor',colour(k));
                hold(app.MeshFig);
            end
            axis(app.MeshFig, 'equal');
            axis(app.MeshFig,[msh.MIN(1) msh.MAX(1) msh.MIN(2)  msh.MAX(2)]);
            % plot invisible figure and save png file
            plot_mesh_GUI(msh,figfilename);

        end

        % Value changed function: layEditField
        function layEditFieldValueChanged(app, event)
            value = app.layEditField.Value;
            changeFilenameColor(app);
            switch value
                case 1
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'off';
                    app.lay3EditField.Visible = 'off';
                    app.lay4EditField.Visible = 'off';
                    app.lay5EditField.Visible = 'off';
                    app.lay6EditField.Visible = 'off';
                    app.lay7EditField.Visible = 'off';
                    app.lay8EditField.Visible = 'off';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'off';
                    app.vol3EditField.Visible = 'off';
                    app.vol4EditField.Visible = 'off';
                    app.vol5EditField.Visible = 'off';
                    app.vol6EditField.Visible = 'off';
                    app.vol7EditField.Visible = 'off';
                    app.vol8EditField.Visible = 'off';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'off';
                    app.h3EditField.Visible = 'off';
                    app.h4EditField.Visible = 'off';
                    app.h5EditField.Visible = 'off';
                    app.h6EditField.Visible = 'off';
                    app.h7EditField.Visible = 'off';
                    app.h8EditField.Visible = 'off';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'off';
                    app.lay3Label.Visible = 'off';
                    app.lay4Label.Visible = 'off';
                    app.lay5Label.Visible = 'off';
                    app.lay6Label.Visible = 'off';
                    app.lay7Label.Visible = 'off';
                    app.lay8Label.Visible = 'off';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
            
                case 2
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'off';
                    app.lay4EditField.Visible = 'off';
                    app.lay5EditField.Visible = 'off';
                    app.lay6EditField.Visible = 'off';
                    app.lay7EditField.Visible = 'off';
                    app.lay8EditField.Visible = 'off';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'off';
                    app.vol4EditField.Visible = 'off';
                    app.vol5EditField.Visible = 'off';
                    app.vol6EditField.Visible = 'off';
                    app.vol7EditField.Visible = 'off';
                    app.vol8EditField.Visible = 'off';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'off';
                    app.h4EditField.Visible = 'off';
                    app.h5EditField.Visible = 'off';
                    app.h6EditField.Visible = 'off';
                    app.h7EditField.Visible = 'off';
                    app.h8EditField.Visible = 'off';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'off';
                    app.lay4Label.Visible = 'off';
                    app.lay5Label.Visible = 'off';
                    app.lay6Label.Visible = 'off';
                    app.lay7Label.Visible = 'off';
                    app.lay8Label.Visible = 'off';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 3
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'off';
                    app.lay5EditField.Visible = 'off';
                    app.lay6EditField.Visible = 'off';
                    app.lay7EditField.Visible = 'off';
                    app.lay8EditField.Visible = 'off';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'off';
                    app.vol5EditField.Visible = 'off';
                    app.vol6EditField.Visible = 'off';
                    app.vol7EditField.Visible = 'off';
                    app.vol8EditField.Visible = 'off';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'off';
                    app.h5EditField.Visible = 'off';
                    app.h6EditField.Visible = 'off';
                    app.h7EditField.Visible = 'off';
                    app.h8EditField.Visible = 'off';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'off';
                    app.lay5Label.Visible = 'off';
                    app.lay6Label.Visible = 'off';
                    app.lay7Label.Visible = 'off';
                    app.lay8Label.Visible = 'off';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 4
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'off';
                    app.lay6EditField.Visible = 'off';
                    app.lay7EditField.Visible = 'off';
                    app.lay8EditField.Visible = 'off';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'off';
                    app.vol6EditField.Visible = 'off';
                    app.vol7EditField.Visible = 'off';
                    app.vol8EditField.Visible = 'off';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'off';
                    app.h6EditField.Visible = 'off';
                    app.h7EditField.Visible = 'off';
                    app.h8EditField.Visible = 'off';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'off';
                    app.lay6Label.Visible = 'off';
                    app.lay7Label.Visible = 'off';
                    app.lay8Label.Visible = 'off';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 5
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'off';
                    app.lay7EditField.Visible = 'off';
                    app.lay8EditField.Visible = 'off';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'off';
                    app.vol7EditField.Visible = 'off';
                    app.vol8EditField.Visible = 'off';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'off';
                    app.h7EditField.Visible = 'off';
                    app.h8EditField.Visible = 'off';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'off';
                    app.lay7Label.Visible = 'off';
                    app.lay8Label.Visible = 'off';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 6
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'off';
                    app.lay8EditField.Visible = 'off';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'off';
                    app.vol8EditField.Visible = 'off';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'off';
                    app.h8EditField.Visible = 'off';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'off';
                    app.lay8Label.Visible = 'off';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 7
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'off';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'off';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'off';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'off';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                    
                case 8
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'off';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'off';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'off';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'off';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 9
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'off';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'off';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'off';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'off';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 10
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'on';
                    app.lay11EditField.Visible = 'off';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'on';
                    app.vol11EditField.Visible = 'off';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'on';
                    app.h11EditField.Visible = 'off';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'on';
                    app.lay11Label.Visible = 'off';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 11
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'on';
                    app.lay11EditField.Visible = 'on';
                    app.lay12EditField.Visible = 'off';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'on';
                    app.vol11EditField.Visible = 'on';
                    app.vol12EditField.Visible = 'off';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'on';
                    app.h11EditField.Visible = 'on';
                    app.h12EditField.Visible = 'off';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'on';
                    app.lay11Label.Visible = 'on';
                    app.lay12Label.Visible = 'off';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 12
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'on';
                    app.lay11EditField.Visible = 'on';
                    app.lay12EditField.Visible = 'on';
                    app.lay13EditField.Visible = 'off';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'on';
                    app.vol11EditField.Visible = 'on';
                    app.vol12EditField.Visible = 'on';
                    app.vol13EditField.Visible = 'off';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'on';
                    app.h11EditField.Visible = 'on';
                    app.h12EditField.Visible = 'on';
                    app.h13EditField.Visible = 'off';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'on';
                    app.lay11Label.Visible = 'on';
                    app.lay12Label.Visible = 'on';
                    app.lay13Label.Visible = 'off';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 13
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'on';
                    app.lay11EditField.Visible = 'on';
                    app.lay12EditField.Visible = 'on';
                    app.lay13EditField.Visible = 'on';
                    app.lay14EditField.Visible = 'off';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'on';
                    app.vol11EditField.Visible = 'on';
                    app.vol12EditField.Visible = 'on';
                    app.vol13EditField.Visible = 'on';
                    app.vol14EditField.Visible = 'off';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'on';
                    app.h11EditField.Visible = 'on';
                    app.h12EditField.Visible = 'on';
                    app.h13EditField.Visible = 'on';
                    app.h14EditField.Visible = 'off';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'on';
                    app.lay11Label.Visible = 'on';
                    app.lay12Label.Visible = 'on';
                    app.lay13Label.Visible = 'on';
                    app.lay14Label.Visible = 'off';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 14
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'on';
                    app.lay11EditField.Visible = 'on';
                    app.lay12EditField.Visible = 'on';
                    app.lay13EditField.Visible = 'on';
                    app.lay14EditField.Visible = 'on';
                    app.lay15EditField.Visible = 'off';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'on';
                    app.vol11EditField.Visible = 'on';
                    app.vol12EditField.Visible = 'on';
                    app.vol13EditField.Visible = 'on';
                    app.vol14EditField.Visible = 'on';
                    app.vol15EditField.Visible = 'off';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'on';
                    app.h11EditField.Visible = 'on';
                    app.h12EditField.Visible = 'on';
                    app.h13EditField.Visible = 'on';
                    app.h14EditField.Visible = 'on';
                    app.h15EditField.Visible = 'off';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'on';
                    app.lay11Label.Visible = 'on';
                    app.lay12Label.Visible = 'on';
                    app.lay13Label.Visible = 'on';
                    app.lay14Label.Visible = 'on';
                    app.lay15Label.Visible = 'off';
                    app.lay16Label.Visible = 'off';
                case 15
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'on';
                    app.lay11EditField.Visible = 'on';
                    app.lay12EditField.Visible = 'on';
                    app.lay13EditField.Visible = 'on';
                    app.lay14EditField.Visible = 'on';
                    app.lay15EditField.Visible = 'on';
                    app.lay16EditField.Visible = 'off';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'on';
                    app.vol11EditField.Visible = 'on';
                    app.vol12EditField.Visible = 'on';
                    app.vol13EditField.Visible = 'on';
                    app.vol14EditField.Visible = 'on';
                    app.vol15EditField.Visible = 'on';
                    app.vol16EditField.Visible = 'off';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'on';
                    app.h11EditField.Visible = 'on';
                    app.h12EditField.Visible = 'on';
                    app.h13EditField.Visible = 'on';
                    app.h14EditField.Visible = 'on';
                    app.h15EditField.Visible = 'on';
                    app.h16EditField.Visible = 'off';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'on';
                    app.lay11Label.Visible = 'on';
                    app.lay12Label.Visible = 'on';
                    app.lay13Label.Visible = 'on';
                    app.lay14Label.Visible = 'on';
                    app.lay15Label.Visible = 'on';
                    app.lay16Label.Visible = 'off';
                case 16
                    app.lay1EditField.Visible = 'on';
                    app.lay2EditField.Visible = 'on';
                    app.lay3EditField.Visible = 'on';
                    app.lay4EditField.Visible = 'on';
                    app.lay5EditField.Visible = 'on';
                    app.lay6EditField.Visible = 'on';
                    app.lay7EditField.Visible = 'on';
                    app.lay8EditField.Visible = 'on';
                    app.lay9EditField.Visible = 'on';
                    app.lay10EditField.Visible = 'on';
                    app.lay11EditField.Visible = 'on';
                    app.lay12EditField.Visible = 'on';
                    app.lay13EditField.Visible = 'on';
                    app.lay14EditField.Visible = 'on';
                    app.lay15EditField.Visible = 'on';
                    app.lay16EditField.Visible = 'on';
                    
                    app.vol1EditField.Visible = 'on';
                    app.vol2EditField.Visible = 'on';
                    app.vol3EditField.Visible = 'on';
                    app.vol4EditField.Visible = 'on';
                    app.vol5EditField.Visible = 'on';
                    app.vol6EditField.Visible = 'on';
                    app.vol7EditField.Visible = 'on';
                    app.vol8EditField.Visible = 'on';
                    app.vol9EditField.Visible = 'on';
                    app.vol10EditField.Visible = 'on';
                    app.vol11EditField.Visible = 'on';
                    app.vol12EditField.Visible = 'on';
                    app.vol13EditField.Visible = 'on';
                    app.vol14EditField.Visible = 'on';
                    app.vol15EditField.Visible = 'on';
                    app.vol16EditField.Visible = 'on';
                    
                    app.h1EditField.Visible = 'on';
                    app.h2EditField.Visible = 'on';
                    app.h3EditField.Visible = 'on';
                    app.h4EditField.Visible = 'on';
                    app.h5EditField.Visible = 'on';
                    app.h6EditField.Visible = 'on';
                    app.h7EditField.Visible = 'on';
                    app.h8EditField.Visible = 'on';
                    app.h9EditField.Visible = 'on';
                    app.h10EditField.Visible = 'on';
                    app.h11EditField.Visible = 'on';
                    app.h12EditField.Visible = 'on';
                    app.h13EditField.Visible = 'on';
                    app.h14EditField.Visible = 'on';
                    app.h15EditField.Visible = 'on';
                    app.h16EditField.Visible = 'on';
                    
                    app.lay1Label.Visible = 'on';
                    app.lay2Label.Visible = 'on';
                    app.lay3Label.Visible = 'on';
                    app.lay4Label.Visible = 'on';
                    app.lay5Label.Visible = 'on';
                    app.lay6Label.Visible = 'on';
                    app.lay7Label.Visible = 'on';
                    app.lay8Label.Visible = 'on';
                    app.lay9Label.Visible = 'on';
                    app.lay10Label.Visible = 'on';
                    app.lay11Label.Visible = 'on';
                    app.lay12Label.Visible = 'on';
                    app.lay13Label.Visible = 'on';
                    app.lay14Label.Visible = 'on';
                    app.lay15Label.Visible = 'on';
                    app.lay16Label.Visible = 'on';
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            AllData.lay = app.layEditField.Value;
            save(['GUI',filesep,app.FilenameEditField.Value],'AllData')
            app.FilenameEditField.FontColor = [0,0,1]; % blue
            app.FilenameEditFieldLabel.FontColor = [0,0,1]; % blue
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            uiopen('load');
            app.layEditField.Value = AllData.lay;
            layEditFieldValueChanged(app, event);
        end

        % Value changed function: delamination_layerEditField
        function delamination_layerEditFieldValueChanged(app, event)
            value = app.delamination_layerEditField.Value;
            if(app.layEditField.Value<value)
                app.delamination_layerEditField.Value = app.layEditField.Value -1;
            end
            changeFilenameColor(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.LoadMenu = uimenu(app.UIFigure);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveMenu = uimenu(app.UIFigure);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MeshTab = uitab(app.TabGroup);
            app.MeshTab.Title = 'Mesh';
            app.MeshFig = uiaxes(app.MeshTab);
            title(app.MeshFig, 'Mesh')
            app.ActuatorcoordinatesLabel = uilabel(app.MeshTab);
            app.ActuatorcoordinatesLabel.Text = 'Actuator coordinates';
            app.xpztEditFieldLabel = uilabel(app.MeshTab);
            app.xpztEditFieldLabel.Text = 'xpzt';
            app.xpztEditField = uieditfield(app.MeshTab, 'numeric');
            app.xpztEditField.Limits = [0 2];
            app.xpztEditField.Value = 0.25;
            app.PlateLengthEditFieldLabel = uilabel(app.MeshTab);
            app.PlateLengthEditFieldLabel.Text = 'Plate Length';
            app.PlateLengthEditField = uieditfield(app.MeshTab, 'numeric');
            app.PlateLengthEditField.LowerLimitInclusive = 'off';
            app.PlateLengthEditField.UpperLimitInclusive = 'off';
            app.PlateLengthEditField.Limits = [0.01 2];
            app.PlateLengthEditField.Value = 0.5;
            app.PlateWidthEditFieldLabel = uilabel(app.MeshTab);
            app.PlateWidthEditFieldLabel.Text = 'Plate Width';
            app.PlateWidthEditField = uieditfield(app.MeshTab, 'numeric');
            app.PlateWidthEditField.LowerLimitInclusive = 'off';
            app.PlateWidthEditField.UpperLimitInclusive = 'off';
            app.PlateWidthEditField.Limits = [0.01 2];
            app.PlateWidthEditField.Value = 0.5;
            app.ypztEditFieldLabel = uilabel(app.MeshTab);
            app.ypztEditFieldLabel.Text = 'ypzt';
            app.ypztEditField = uieditfield(app.MeshTab, 'numeric');
            app.ypztEditField.LowerLimitInclusive = 'off';
            app.ypztEditField.UpperLimitInclusive = 'off';
            app.ypztEditField.Limits = [0 2];
            app.ypztEditField.Value = 0.25;
            app.NxEditFieldLabel = uilabel(app.MeshTab);
            app.NxEditFieldLabel.Text = 'Nx';
            app.NxEditField = uieditfield(app.MeshTab, 'numeric');
            app.NxEditField.LowerLimitInclusive = 'off';
            app.NxEditField.UpperLimitInclusive = 'off';
            app.NxEditField.Limits = [100 1024];
            app.NxEditField.ValueDisplayFormat = '%.0f';
            app.NxEditField.Value = 500;
            app.NyEditFieldLabel = uilabel(app.MeshTab);
            app.NyEditFieldLabel.Text = 'Ny';
            app.NyEditField = uieditfield(app.MeshTab, 'numeric');
            app.NyEditField.LowerLimitInclusive = 'off';
            app.NyEditField.UpperLimitInclusive = 'off';
            app.NyEditField.Limits = [100 1024];
            app.NyEditField.ValueDisplayFormat = '%.0f';
            app.NyEditField.Value = 500;
            app.NumberofcorrespondingintepolationpointsLabel = uilabel(app.MeshTab);
            app.NumberofcorrespondingintepolationpointsLabel.Text = 'Number of corresponding intepolation points';
            app.DelaminationLabel = uilabel(app.MeshTab);
            app.DelaminationLabel.Text = 'Delamination';
            app.DelaminationcentercoordinatesLabel = uilabel(app.MeshTab);
            app.DelaminationcentercoordinatesLabel.Text = 'Delamination center coordinates';
            app.xCenterEditFieldLabel = uilabel(app.MeshTab);
            app.xCenterEditFieldLabel.Text = 'xCenter';
            app.xCenterEditField = uieditfield(app.MeshTab, 'numeric');
            app.xCenterEditField.Limits = [0 2];
            app.xCenterEditField.Value = 0.25;
            app.yCenterEditFieldLabel = uilabel(app.MeshTab);
            app.yCenterEditFieldLabel.Text = 'yCenter';
            app.yCenterEditField = uieditfield(app.MeshTab, 'numeric');
            app.yCenterEditField.LowerLimitInclusive = 'off';
            app.yCenterEditField.UpperLimitInclusive = 'off';
            app.yCenterEditField.Limits = [0 2];
            app.yCenterEditField.Value = 0.375;
            app.aEditFieldLabel = uilabel(app.MeshTab);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.MeshTab, 'numeric');
            app.aEditField.Limits = [0.001 0.25];
            app.aEditField.Value = 0.02;
            app.bEditFieldLabel = uilabel(app.MeshTab);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.MeshTab, 'numeric');
            app.bEditField.LowerLimitInclusive = 'off';
            app.bEditField.UpperLimitInclusive = 'off';
            app.bEditField.Limits = [0.001 0.25];
            app.bEditField.Value = 0.01;
            app.DelaminationsemimajorandsemiminoraxislengthLabel = uilabel(app.MeshTab);
            app.DelaminationsemimajorandsemiminoraxislengthLabel.Text = 'Delamination semi-major and semi-minor axis length';
            app.pztradiusEditFieldLabel = uilabel(app.MeshTab);
            app.pztradiusEditFieldLabel.Text = 'pzt radius';
            app.pztradiusEditField = uieditfield(app.MeshTab, 'numeric');
            app.pztradiusEditField.Limits = [0.001 0.025];
            app.pztradiusEditField.Value = 0.005;
            app.DelaminationRotationAngledegEditFieldLabel = uilabel(app.MeshTab);
            app.DelaminationRotationAngledegEditFieldLabel.Text = 'Delamination Rotation Angle [deg]';
            app.DelaminationRotationAngledegEditField = uieditfield(app.MeshTab, 'numeric');
            app.DelaminationRotationAngledegEditField.Limits = [0 179];
            app.DelaminationRotationAngledegEditField.Value = 30;
            app.CreateMeshButton = uibutton(app.MeshTab, 'push');
            app.CreateMeshButton.ButtonPushedFcn = createCallbackFcn(app, @CreateMeshButtonPushed, true);
            app.CreateMeshButton.Text = 'Create Mesh';
            app.MaterialsTab = uitab(app.TabGroup);
            app.MaterialsTab.Title = 'Materials';
            app.MatrixLabel = uilabel(app.MaterialsTab);
            app.MatrixLabel.Text = 'Matrix';
            app.FibresLabel = uilabel(app.MaterialsTab);
            app.FibresLabel.Text = 'Fibres';
            app.EmEditFieldLabel = uilabel(app.MaterialsTab);
            app.EmEditFieldLabel.Text = 'Em';
            app.EmEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.EmEditField.Limits = [0 Inf];
            app.EmEditField.Value = 3.43;
            app.EfEditFieldLabel = uilabel(app.MaterialsTab);
            app.EfEditFieldLabel.Text = 'Ef';
            app.EfEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.EfEditField.Limits = [0 Inf];
            app.EfEditField.Value = 275;
            app.nimEditFieldLabel = uilabel(app.MaterialsTab);
            app.nimEditFieldLabel.Text = 'nim';
            app.nimEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.nimEditField.Limits = [0 Inf];
            app.nimEditField.Value = 0.35;
            app.nifEditFieldLabel = uilabel(app.MaterialsTab);
            app.nifEditFieldLabel.Text = 'nif';
            app.nifEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.nifEditField.Limits = [0 Inf];
            app.nifEditField.Value = 0.2;
            app.YoungsModulusGPaLabel = uilabel(app.MaterialsTab);
            app.YoungsModulusGPaLabel.Text = 'Young''s Modulus [GPa]';
            app.PiossonratioLabel = uilabel(app.MaterialsTab);
            app.PiossonratioLabel.Text = 'Piosson ratio';
            app.Densitykgm3Label = uilabel(app.MaterialsTab);
            app.Densitykgm3Label.Text = 'Density [kg/m^3]';
            app.rhomEditFieldLabel = uilabel(app.MaterialsTab);
            app.rhomEditFieldLabel.Text = 'rhom';
            app.rhomEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.rhomEditField.Limits = [0 Inf];
            app.rhomEditField.Value = 1097;
            app.rhofEditFieldLabel = uilabel(app.MaterialsTab);
            app.rhofEditFieldLabel.Text = 'rhof';
            app.rhofEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.rhofEditField.Limits = [0 Inf];
            app.rhofEditField.Value = 1900;
            app.NooflayersLabel = uilabel(app.MaterialsTab);
            app.NooflayersLabel.Text = 'No of layers';
            app.layEditFieldLabel = uilabel(app.MaterialsTab);
            app.layEditFieldLabel.Text = 'lay';
            app.layEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.layEditField.Limits = [1 16];
            app.layEditField.ValueDisplayFormat = '%.0f';
            app.layEditField.ValueChangedFcn = createCallbackFcn(app, @layEditFieldValueChanged, true);
            app.layEditField.Value = 8;
            app.EditFieldLabel = uilabel(app.MaterialsTab);
            app.EditFieldLabel.Text = '';
            app.lay1EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay1EditField.Limits = [-90 90];
            app.lay1EditField.ValueDisplayFormat = '%.0f';
            app.LayupLabel = uilabel(app.MaterialsTab);
            app.LayupLabel.Text = 'Layup';
            app.VolLabel = uilabel(app.MaterialsTab);
            app.VolLabel.Text = 'Vol [%]';
            app.EditFieldLabel_16 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_16.Text = '';
            app.vol1EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol1EditField.Limits = [0 100];
            app.vol1EditField.Value = 50;
            app.EditField_2Label = uilabel(app.MaterialsTab);
            app.EditField_2Label.Text = '';
            app.lay2EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay2EditField.Limits = [-90 90];
            app.lay2EditField.ValueDisplayFormat = '%.0f';
            app.lay2EditField.Value = 90;
            app.EditFieldLabel_17 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_17.Text = '';
            app.vol2EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol2EditField.Limits = [0 100];
            app.vol2EditField.Value = 50;
            app.EditFieldLabel_2 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_2.Text = '';
            app.lay3EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay3EditField.Limits = [-90 90];
            app.lay3EditField.ValueDisplayFormat = '%.0f';
            app.EditFieldLabel_18 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_18.Text = '';
            app.vol3EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol3EditField.Limits = [0 100];
            app.vol3EditField.Value = 50;
            app.EditFieldLabel_3 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_3.Text = '';
            app.lay4EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay4EditField.Limits = [-90 90];
            app.lay4EditField.ValueDisplayFormat = '%.0f';
            app.lay4EditField.Value = 90;
            app.EditFieldLabel_19 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_19.Text = '';
            app.vol4EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol4EditField.Limits = [0 100];
            app.vol4EditField.Value = 50;
            app.EditFieldLabel_4 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_4.Text = '';
            app.lay5EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay5EditField.Limits = [-90 90];
            app.lay5EditField.ValueDisplayFormat = '%.0f';
            app.lay5EditField.Value = 90;
            app.EditFieldLabel_20 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_20.Text = '';
            app.vol5EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol5EditField.Limits = [0 100];
            app.vol5EditField.Value = 50;
            app.EditFieldLabel_5 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_5.Text = '';
            app.lay6EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay6EditField.Limits = [-90 90];
            app.lay6EditField.ValueDisplayFormat = '%.0f';
            app.EditFieldLabel_21 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_21.Text = '';
            app.vol6EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol6EditField.Limits = [0 100];
            app.vol6EditField.Value = 50;
            app.EditFieldLabel_6 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_6.Text = '';
            app.lay7EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay7EditField.Limits = [-90 90];
            app.lay7EditField.ValueDisplayFormat = '%.0f';
            app.lay7EditField.Value = 90;
            app.EditFieldLabel_22 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_22.Text = '';
            app.vol7EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol7EditField.Limits = [0 100];
            app.vol7EditField.Value = 50;
            app.EditFieldLabel_7 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_7.Text = '';
            app.lay8EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay8EditField.Limits = [-90 90];
            app.lay8EditField.ValueDisplayFormat = '%.0f';
            app.EditFieldLabel_23 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_23.Text = '';
            app.vol8EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol8EditField.Limits = [0 100];
            app.vol8EditField.Value = 50;
            app.ThicknessmmLabel = uilabel(app.MaterialsTab);
            app.ThicknessmmLabel.Text = 'Thickness [mm]';
            app.EditFieldLabel_32 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_32.Text = '';
            app.h1EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h1EditField.Limits = [0 50];
            app.h1EditField.Value = 0.1;
            app.EditFieldLabel_33 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_33.Text = '';
            app.h2EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h2EditField.Limits = [0 50];
            app.h2EditField.Value = 0.1;
            app.EditFieldLabel_34 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_34.Text = '';
            app.h3EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h3EditField.Limits = [0 50];
            app.h3EditField.Value = 0.1;
            app.EditFieldLabel_35 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_35.Text = '';
            app.h4EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h4EditField.Limits = [0 50];
            app.h4EditField.Value = 0.1;
            app.EditFieldLabel_36 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_36.Text = '';
            app.h5EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h5EditField.Limits = [0 50];
            app.h5EditField.Value = 0.1;
            app.EditFieldLabel_37 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_37.Text = '';
            app.h6EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h6EditField.Limits = [0 50];
            app.h6EditField.Value = 0.1;
            app.EditFieldLabel_38 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_38.Text = '';
            app.h7EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h7EditField.Limits = [0 50];
            app.h7EditField.Value = 0.1;
            app.EditFieldLabel_39 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_39.Text = '';
            app.h8EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h8EditField.Limits = [0 50];
            app.h8EditField.Value = 0.1;
            app.EditFieldLabel_8 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_8.Text = '';
            app.lay9EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay9EditField.Limits = [-90 90];
            app.lay9EditField.ValueDisplayFormat = '%.0f';
            app.EditFieldLabel_24 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_24.Text = '';
            app.vol9EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol9EditField.Limits = [0 100];
            app.vol9EditField.Value = 50;
            app.EditFieldLabel_9 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_9.Text = '';
            app.lay10EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay10EditField.Limits = [-90 90];
            app.lay10EditField.ValueDisplayFormat = '%.0f';
            app.lay10EditField.Value = 90;
            app.EditFieldLabel_25 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_25.Text = '';
            app.vol10EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol10EditField.Limits = [0 100];
            app.vol10EditField.Value = 50;
            app.EditFieldLabel_10 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_10.Text = '';
            app.lay11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay11EditField.Limits = [-90 90];
            app.lay11EditField.ValueDisplayFormat = '%.0f';
            app.EditFieldLabel_26 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_26.Text = '';
            app.vol11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol11EditField.Limits = [0 100];
            app.vol11EditField.Value = 50;
            app.EditFieldLabel_11 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_11.Text = '';
            app.lay12EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay12EditField.Limits = [-90 90];
            app.lay12EditField.ValueDisplayFormat = '%.0f';
            app.lay12EditField.Value = 90;
            app.EditFieldLabel_27 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_27.Text = '';
            app.vol12EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol12EditField.Limits = [0 100];
            app.vol12EditField.Value = 50;
            app.EditFieldLabel_12 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_12.Text = '';
            app.lay13EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay13EditField.Limits = [-90 90];
            app.lay13EditField.ValueDisplayFormat = '%.0f';
            app.lay13EditField.Value = 90;
            app.EditFieldLabel_28 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_28.Text = '';
            app.vol13EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol13EditField.Limits = [0 100];
            app.vol13EditField.Value = 50;
            app.EditFieldLabel_13 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_13.Text = '';
            app.lay14EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay14EditField.Limits = [-90 90];
            app.lay14EditField.ValueDisplayFormat = '%.0f';
            app.EditFieldLabel_29 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_29.Text = '';
            app.vol14EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol14EditField.Limits = [0 100];
            app.vol14EditField.Value = 50;
            app.EditFieldLabel_14 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_14.Text = '';
            app.lay15EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay15EditField.Limits = [-90 90];
            app.lay15EditField.ValueDisplayFormat = '%.0f';
            app.lay15EditField.Value = 90;
            app.EditFieldLabel_30 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_30.Text = '';
            app.vol15EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol15EditField.Limits = [0 100];
            app.vol15EditField.Value = 50;
            app.EditFieldLabel_15 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_15.Text = '';
            app.lay16EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.lay16EditField.Limits = [-90 90];
            app.lay16EditField.ValueDisplayFormat = '%.0f';
            app.EditFieldLabel_31 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_31.Text = '';
            app.vol16EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.vol16EditField.Limits = [0 100];
            app.vol16EditField.Value = 50;
            app.EditFieldLabel_40 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_40.Text = '';
            app.h9EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h9EditField.Limits = [0 50];
            app.h9EditField.Value = 0.1;
            app.EditFieldLabel_41 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_41.Text = '';
            app.h10EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h10EditField.Limits = [0 50];
            app.h10EditField.Value = 0.1;
            app.EditFieldLabel_42 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_42.Text = '';
            app.h11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h11EditField.Limits = [0 50];
            app.h11EditField.Value = 0.1;
            app.EditFieldLabel_43 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_43.Text = '';
            app.h12EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h12EditField.Limits = [0 50];
            app.h12EditField.Value = 0.1;
            app.EditFieldLabel_44 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_44.Text = '';
            app.h13EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h13EditField.Limits = [0 50];
            app.h13EditField.Value = 0.1;
            app.EditFieldLabel_45 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_45.Text = '';
            app.h14EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h14EditField.Limits = [0 50];
            app.h14EditField.Value = 0.1;
            app.EditFieldLabel_46 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_46.Text = '';
            app.h15EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h15EditField.Limits = [0 50];
            app.h15EditField.Value = 0.1;
            app.EditFieldLabel_47 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_47.Text = '';
            app.h16EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.h16EditField.Limits = [0 50];
            app.h16EditField.Value = 0.1;
            app.PiezoelectrictransducerpropertiesLabel = uilabel(app.MaterialsTab);
            app.PiezoelectrictransducerpropertiesLabel.Text = 'Piezoelectric transducer properties';
            app.YoungsModulusGPaLabel_2 = uilabel(app.MaterialsTab);
            app.YoungsModulusGPaLabel_2.Text = 'Young''s Modulus [GPa]';
            app.PiossonratioLabel_2 = uilabel(app.MaterialsTab);
            app.PiossonratioLabel_2.Text = 'Piosson ratio';
            app.Densitykgm3Label_2 = uilabel(app.MaterialsTab);
            app.Densitykgm3Label_2.Text = 'Density [kg/m^3]';
            app.YE11EditFieldLabel = uilabel(app.MaterialsTab);
            app.YE11EditFieldLabel.Text = 'YE11';
            app.YE11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.YE11EditField.Limits = [0 Inf];
            app.YE11EditField.Value = 5.9;
            app.nu11EditFieldLabel = uilabel(app.MaterialsTab);
            app.nu11EditFieldLabel.Text = 'nu11';
            app.nu11EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.nu11EditField.Limits = [0 Inf];
            app.nu11EditField.Value = 0.32;
            app.rho_pztEditFieldLabel = uilabel(app.MaterialsTab);
            app.rho_pztEditFieldLabel.Text = 'rho_pzt';
            app.rho_pztEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.rho_pztEditField.Limits = [0 Inf];
            app.rho_pztEditField.Value = 7850;
            app.YE33EditFieldLabel = uilabel(app.MaterialsTab);
            app.YE33EditFieldLabel.Text = 'YE33';
            app.YE33EditField = uieditfield(app.MaterialsTab, 'numeric');
            app.YE33EditField.Limits = [0 Inf];
            app.YE33EditField.Value = 4.8;
            app.Chargeconstantmatrix1012CNLabel = uilabel(app.MaterialsTab);
            app.Chargeconstantmatrix1012CNLabel.Text = 'Charge constant matrix [10^(-12) C/N]';
            app.Voltageconstantmatrix103VmNLabel = uilabel(app.MaterialsTab);
            app.Voltageconstantmatrix103VmNLabel.Text = 'Voltage constant matrix [10^(-3) Vm/N]';
            app.Label_2 = uilabel(app.MaterialsTab);
            app.Label_2.Text = '';
            app.dp11 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_3 = uilabel(app.MaterialsTab);
            app.Label_3.Text = '';
            app.dp12 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_4 = uilabel(app.MaterialsTab);
            app.Label_4.Text = '';
            app.dp13 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_5 = uilabel(app.MaterialsTab);
            app.Label_5.Text = '';
            app.dp14 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_6 = uilabel(app.MaterialsTab);
            app.Label_6.Text = '';
            app.dp15 = uieditfield(app.MaterialsTab, 'numeric');
            app.dp15.Value = 669;
            app.Label_7 = uilabel(app.MaterialsTab);
            app.Label_7.Text = '';
            app.dp16 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_8 = uilabel(app.MaterialsTab);
            app.Label_8.Text = '';
            app.dp21 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_9 = uilabel(app.MaterialsTab);
            app.Label_9.Text = '';
            app.dp22 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_10 = uilabel(app.MaterialsTab);
            app.Label_10.Text = '';
            app.dp23 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_11 = uilabel(app.MaterialsTab);
            app.Label_11.Text = '';
            app.dp24 = uieditfield(app.MaterialsTab, 'numeric');
            app.dp24.Value = 669;
            app.Label_12 = uilabel(app.MaterialsTab);
            app.Label_12.Text = '';
            app.dp25 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_13 = uilabel(app.MaterialsTab);
            app.Label_13.Text = '';
            app.dp26 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_14 = uilabel(app.MaterialsTab);
            app.Label_14.Text = '';
            app.dp31 = uieditfield(app.MaterialsTab, 'numeric');
            app.dp31.Value = -208;
            app.Label_15 = uilabel(app.MaterialsTab);
            app.Label_15.Text = '';
            app.dp32 = uieditfield(app.MaterialsTab, 'numeric');
            app.dp32.Value = -208;
            app.Label_16 = uilabel(app.MaterialsTab);
            app.Label_16.Text = '';
            app.dp33 = uieditfield(app.MaterialsTab, 'numeric');
            app.dp33.Value = 443;
            app.Label_17 = uilabel(app.MaterialsTab);
            app.Label_17.Text = '';
            app.dp34 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_18 = uilabel(app.MaterialsTab);
            app.Label_18.Text = '';
            app.dp35 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_19 = uilabel(app.MaterialsTab);
            app.Label_19.Text = '';
            app.dp36 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_20 = uilabel(app.MaterialsTab);
            app.Label_20.Text = '';
            app.gp11 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_21 = uilabel(app.MaterialsTab);
            app.Label_21.Text = '';
            app.gp12 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_22 = uilabel(app.MaterialsTab);
            app.Label_22.Text = '';
            app.gp13 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_23 = uilabel(app.MaterialsTab);
            app.Label_23.Text = '';
            app.gp14 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_24 = uilabel(app.MaterialsTab);
            app.Label_24.Text = '';
            app.gp15 = uieditfield(app.MaterialsTab, 'numeric');
            app.gp15.Value = 38.9;
            app.Label_25 = uilabel(app.MaterialsTab);
            app.Label_25.Text = '';
            app.gp16 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_26 = uilabel(app.MaterialsTab);
            app.Label_26.Text = '';
            app.gp21 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_27 = uilabel(app.MaterialsTab);
            app.Label_27.Text = '';
            app.gp22 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_28 = uilabel(app.MaterialsTab);
            app.Label_28.Text = '';
            app.gp23 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_29 = uilabel(app.MaterialsTab);
            app.Label_29.Text = '';
            app.gp24 = uieditfield(app.MaterialsTab, 'numeric');
            app.gp24.Value = 38.9;
            app.Label_30 = uilabel(app.MaterialsTab);
            app.Label_30.Text = '';
            app.gp25 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_31 = uilabel(app.MaterialsTab);
            app.Label_31.Text = '';
            app.gp26 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_32 = uilabel(app.MaterialsTab);
            app.Label_32.Text = '';
            app.gp31 = uieditfield(app.MaterialsTab, 'numeric');
            app.gp31.Value = -12.4;
            app.Label_33 = uilabel(app.MaterialsTab);
            app.Label_33.Text = '';
            app.gp32 = uieditfield(app.MaterialsTab, 'numeric');
            app.gp32.Value = -12.4;
            app.Label_34 = uilabel(app.MaterialsTab);
            app.Label_34.Text = '';
            app.gp33 = uieditfield(app.MaterialsTab, 'numeric');
            app.gp33.Value = 26.3;
            app.Label_35 = uilabel(app.MaterialsTab);
            app.Label_35.Text = '';
            app.gp34 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_36 = uilabel(app.MaterialsTab);
            app.Label_36.Text = '';
            app.gp35 = uieditfield(app.MaterialsTab, 'numeric');
            app.Label_37 = uilabel(app.MaterialsTab);
            app.Label_37.Text = '';
            app.gp36 = uieditfield(app.MaterialsTab, 'numeric');
            app.dLabel = uilabel(app.MaterialsTab);
            app.dLabel.Text = 'd';
            app.gLabel = uilabel(app.MaterialsTab);
            app.gLabel.Text = 'g';
            app.lay1Label = uilabel(app.MaterialsTab);
            app.lay1Label.Text = '1';
            app.lay2Label = uilabel(app.MaterialsTab);
            app.lay2Label.Text = '2';
            app.lay3Label = uilabel(app.MaterialsTab);
            app.lay3Label.Text = '3';
            app.lay4Label = uilabel(app.MaterialsTab);
            app.lay4Label.Text = '4';
            app.lay5Label = uilabel(app.MaterialsTab);
            app.lay5Label.Text = '5';
            app.lay6Label = uilabel(app.MaterialsTab);
            app.lay6Label.Text = '6';
            app.lay7Label = uilabel(app.MaterialsTab);
            app.lay7Label.Text = '7';
            app.lay8Label = uilabel(app.MaterialsTab);
            app.lay8Label.Text = '8';
            app.lay9Label = uilabel(app.MaterialsTab);
            app.lay9Label.Text = '9';
            app.lay10Label = uilabel(app.MaterialsTab);
            app.lay10Label.Text = '10';
            app.lay11Label = uilabel(app.MaterialsTab);
            app.lay11Label.Text = '11';
            app.lay12Label = uilabel(app.MaterialsTab);
            app.lay12Label.Text = '12';
            app.lay13Label = uilabel(app.MaterialsTab);
            app.lay13Label.Text = '13';
            app.lay14Label = uilabel(app.MaterialsTab);
            app.lay14Label.Text = '14';
            app.lay15Label = uilabel(app.MaterialsTab);
            app.lay15Label.Text = '15';
            app.lay16Label = uilabel(app.MaterialsTab);
            app.lay16Label.Text = '16';
            app.ShellstructurepropertiesLabel = uilabel(app.MaterialsTab);
            app.ShellstructurepropertiesLabel.Text = 'Shell structure properties';
            app.PiezoelectrictransducerthicknessmmEditFieldLabel = uilabel(app.MaterialsTab);
            app.PiezoelectrictransducerthicknessmmEditFieldLabel.Text = 'Piezoelectric transducer thickness [mm]';
            app.pzt_thicknessEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.pzt_thicknessEditField.Limits = [0 10];
            app.pzt_thicknessEditField.Value = 0.5;
            app.DelaminationlayerLabel = uilabel(app.MaterialsTab);
            app.DelaminationlayerLabel.Text = 'Delamination layer';
            app.EditFieldLabel_48 = uilabel(app.MaterialsTab);
            app.EditFieldLabel_48.Text = '';
            app.delamination_layerEditField = uieditfield(app.MaterialsTab, 'numeric');
            app.delamination_layerEditField.Limits = [1 15];
            app.delamination_layerEditField.ValueDisplayFormat = '%.0f';
            app.delamination_layerEditField.ValueChangedFcn = createCallbackFcn(app, @delamination_layerEditFieldValueChanged, true);
            app.delamination_layerEditField.Value = 2;
            app.SignalTab = uitab(app.TabGroup);
            app.SignalTab.Title = 'Signal';
            app.CarrierFrequencykHzEditFieldLabel = uilabel(app.SignalTab);
            app.CarrierFrequencykHzEditFieldLabel.Text = 'Carrier Frequency [kHz]';
            app.CarrierFrequencykHzEditField = uieditfield(app.SignalTab, 'numeric');
            app.CarrierFrequencykHzEditField.Limits = [0 500];
            app.CarrierFrequencykHzEditField.Value = 50;
            app.ModulationFrequencykHzEditFieldLabel = uilabel(app.SignalTab);
            app.ModulationFrequencykHzEditFieldLabel.Text = 'Modulation Frequency [kHz]';
            app.ModulationFrequencykHzEditField = uieditfield(app.SignalTab, 'numeric');
            app.ModulationFrequencykHzEditField.Limits = [0 500];
            app.ModulationFrequencykHzEditField.Value = 10;
            app.NoofsamplesEditFieldLabel = uilabel(app.SignalTab);
            app.NoofsamplesEditFieldLabel.Text = 'No of samples';
            app.NoofsamplesEditField = uieditfield(app.SignalTab, 'numeric');
            app.NoofsamplesEditField.Limits = [0 Inf];
            app.NoofsamplesEditField.ValueDisplayFormat = '%.0f';
            app.NoofsamplesEditField.Value = 75000;
            app.TotalpropagationtimemsEditFieldLabel = uilabel(app.SignalTab);
            app.TotalpropagationtimemsEditFieldLabel.Text = 'Total propagation time [ms]';
            app.TotalpropagationtimemsEditField = uieditfield(app.SignalTab, 'numeric');
            app.TotalpropagationtimemsEditField.Limits = [0 5];
            app.TotalpropagationtimemsEditField.Value = 0.75;
            app.NooftimeframesEditFieldLabel = uilabel(app.SignalTab);
            app.NooftimeframesEditFieldLabel.Text = 'No of time frames';
            app.NooftimeframesEditField = uieditfield(app.SignalTab, 'numeric');
            app.NooftimeframesEditField.Limits = [0 Inf];
            app.NooftimeframesEditField.ValueDisplayFormat = '%.0f';
            app.NooftimeframesEditField.Value = 512;
            app.PeakvoltageVEditFieldLabel = uilabel(app.SignalTab);
            app.PeakvoltageVEditFieldLabel.Text = 'Peak voltage [V]';
            app.PeakvoltageVEditField = uieditfield(app.SignalTab, 'numeric');
            app.PeakvoltageVEditField.Limits = [0 240];
            app.PeakvoltageVEditField.Value = 10;
            app.HannTimeFig = uiaxes(app.SignalTab);
            title(app.HannTimeFig, 'Hann Signal')
            xlabel(app.HannTimeFig, 'Time [ms]')
            app.HannFrequencyFig = uiaxes(app.SignalTab);
            xlabel(app.HannFrequencyFig, 'Frequency [kHz]')
            app.ProcessingTab = uitab(app.TabGroup);
            app.ProcessingTab.Title = 'Processing';
            app.Label = uilabel(app.ProcessingTab);
            app.Label.Text = '';
            app.Mode = uiswitch(app.ProcessingTab, 'slider');
            app.Mode.Items = {'CPU', 'GPU'};
            app.Mode.Value = 'CPU';
            app.FieldvariableDropDownLabel = uilabel(app.ProcessingTab);
            app.FieldvariableDropDownLabel.Text = 'Field variable';
            app.FieldvariableDropDown = uidropdown(app.ProcessingTab);
            app.FieldvariableDropDown.Items = {'Displacement', 'Velocity', 'Acceleration', 'All'};
            app.FieldvariableDropDown.Value = 'Velocity';
            app.ShellsurfaceDropDownLabel = uilabel(app.ProcessingTab);
            app.ShellsurfaceDropDownLabel.Text = 'Shell surface';
            app.ShellsurfaceDropDown = uidropdown(app.ProcessingTab);
            app.ShellsurfaceDropDown.Items = {'Top', 'Bottom', 'Top and Bottom'};
            app.ShellsurfaceDropDown.Value = 'Top';
            app.NotificationTextAreaLabel = uilabel(app.ProcessingTab);
            app.NotificationTextAreaLabel.Text = 'Notification';
            app.NotificationTextArea = uitextarea(app.ProcessingTab);
            app.Motion1CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion1CheckBox.Text = 'Ux';
            app.Motion2CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion2CheckBox.Text = 'Uy';
            app.Motion3CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion3CheckBox.Text = 'Uz';
            app.Motion3CheckBox.Value = true;
            app.Motion4CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion4CheckBox.Text = 'Fix';
            app.Motion5CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion5CheckBox.Text = 'Fiy';
            app.Motion6CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion6CheckBox.Text = 'Ux+h/2*UFix';
            app.Motion7CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion7CheckBox.Text = 'Uy+h/2*UFiy';
            app.Motion8CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion8CheckBox.Text = 'sqrt((Ux+h/2.*UFix).^2+(Uy+h/2.*UFiy).^2)';
            app.Motion8CheckBox.Value = true;
            app.Motion9CheckBox = uicheckbox(app.ProcessingTab);
            app.Motion9CheckBox.Text = 'sqrt((Ux+h/2.*UFix).^2+(Uy+h/2.*UFiy).^2 + Uz.^2)';
            app.DeleteframesSwitchLabel = uilabel(app.ProcessingTab);
            app.DeleteframesSwitchLabel.Text = 'Delete frames';
            app.DeleteframesSwitch = uiswitch(app.ProcessingTab, 'slider');
            app.DeleteframesSwitch.Items = {'No', 'Yes'};
            app.DeleteframesSwitch.Value = 'No';
            app.NormalizationSwitchLabel = uilabel(app.ProcessingTab);
            app.NormalizationSwitchLabel.Text = 'Normalization';
            app.NormalizationSwitch = uiswitch(app.ProcessingTab, 'slider');
            app.NormalizationSwitch.Items = {'No', 'Yes'};
            app.NormalizationSwitch.Value = 'No';
            app.RunButton = uibutton(app.ProcessingTab, 'state');
            app.RunButton.Text = 'Run';
            app.ColormapDropDownLabel = uilabel(app.ProcessingTab);
            app.ColormapDropDownLabel.Text = 'Color map';
            app.ColormapDropDown = uidropdown(app.ProcessingTab);
            app.ColormapDropDown.Items = {'parula', 'jet', 'hsv', 'hot', 'cool', 'spring', 'summer', 'autumn', 'winter', 'gray', 'bone', 'copper', 'pink', 'sunset', 'burd', 'brewer', 'iridescent'};
            app.ColormapDropDown.Value = 'burd';
            app.caxiscutEditFieldLabel = uilabel(app.ProcessingTab);
            app.caxiscutEditFieldLabel.Text = 'caxis cut';
            app.caxiscutEditField = uieditfield(app.ProcessingTab, 'numeric');
            app.caxiscutEditField.Limits = [0 1];
            app.caxiscutEditField.Value = 0.8;
            app.Label_38 = uilabel(app.ProcessingTab);
            app.Label_38.Text = '10%';
            app.Lamp_Progres10 = uilamp(app.ProcessingTab);
            app.Label_39 = uilabel(app.ProcessingTab);
            app.Label_39.Text = '20%';
            app.Lamp_Progres20 = uilamp(app.ProcessingTab);
            app.Label_40 = uilabel(app.ProcessingTab);
            app.Label_40.Text = '30%';
            app.Lamp_Progres30 = uilamp(app.ProcessingTab);
            app.Label_41 = uilabel(app.ProcessingTab);
            app.Label_41.Text = '40%';
            app.Lamp_Progres40 = uilamp(app.ProcessingTab);
            app.Label_42 = uilabel(app.ProcessingTab);
            app.Label_42.Text = '50%';
            app.Lamp_Progres50 = uilamp(app.ProcessingTab);
            app.Label_43 = uilabel(app.ProcessingTab);
            app.Label_43.Text = '60%';
            app.Lamp_Progres60 = uilamp(app.ProcessingTab);
            app.Label_44 = uilabel(app.ProcessingTab);
            app.Label_44.Text = '70%';
            app.Lamp_Progres70 = uilamp(app.ProcessingTab);
            app.Label_45 = uilabel(app.ProcessingTab);
            app.Label_45.Text = '80%';
            app.Lamp_Progres80 = uilamp(app.ProcessingTab);
            app.Label_46 = uilabel(app.ProcessingTab);
            app.Label_46.Text = '90%';
            app.Lamp_Progres90 = uilamp(app.ProcessingTab);
            app.Label_47 = uilabel(app.ProcessingTab);
            app.Label_47.Text = '100%';
            app.Lamp_Progres100 = uilamp(app.ProcessingTab);
            app.FilenameEditFieldLabel = uilabel(app.UIFigure);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.UIFigure, 'text');
            app.FilenameEditField.Value = 'test';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SpectralGuidedWaves

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end